classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        MainPanel                     matlab.ui.container.Panel
        Tabs                          matlab.ui.container.TabGroup
        Data                          matlab.ui.container.Tab
        UITable                       matlab.ui.control.Table
        NextdataselectionButtonGroup  matlab.ui.container.ButtonGroup
        HealthyButton                 matlab.ui.control.RadioButton
        CerebralPalsyButton           matlab.ui.control.RadioButton
        Graphs                        matlab.ui.container.Tab
        GraphsPanel                   matlab.ui.container.Panel
        graph                         matlab.ui.control.UIAxes
        TabGroup                      matlab.ui.container.TabGroup
        PlotoptionsTab                matlab.ui.container.Tab
        SelectdataLabel               matlab.ui.control.Label
        HealthyCheckBox               matlab.ui.control.CheckBox
        CerebralPalsyCheckBox         matlab.ui.control.CheckBox
        PeaksLabel                    matlab.ui.control.Label
        MaximaCheckBox                matlab.ui.control.CheckBox
        MinimaCheckBox_2              matlab.ui.control.CheckBox
        FourieroptionsTab             matlab.ui.container.Tab
        Label                         matlab.ui.control.Label
        WindowDropDownLabel           matlab.ui.control.Label
        WindowDropDown                matlab.ui.control.DropDown
        DataoptionsTab                matlab.ui.container.Tab
        SmoothingLabel                matlab.ui.control.Label
        Switch                        matlab.ui.control.Switch
        WindowSizeSliderLabel         matlab.ui.control.Label
        WindowSizeSlider              matlab.ui.control.Slider
        SaveTab                       matlab.ui.container.Tab
        CommentsTextAreaLabel         matlab.ui.control.Label
        Comments                      matlab.ui.control.TextArea
        SaveasButton                  matlab.ui.control.Button
        openFilePanel                 matlab.ui.container.Panel
        OpenfileButton                matlab.ui.control.Button
        CurrentfileEditFieldLabel     matlab.ui.control.Label
        CurrentfileEditField          matlab.ui.control.EditField
        HelpButton                    matlab.ui.control.Button
    end

    
    properties (Access = private)
        healthyData         % Contains the data of a healthy child
        cerebralPalsyData   % Contains the data of a child w/ cerebral palsy
        healthyDataSmoothed 
        cerebralPalsyDataSmoothed
        HelpPopup           %Help window
        smoothOn = false;
        maxima
        minima
    end
    
    methods (Access = private)
        
        function showData(app, healthyData, cerebralPalsyData)
            
            cla(app.graph); %clear graph
            
            if app.HealthyCheckBox.Value == 1 %If healthy is on: draw healthy data
                plot(app.graph, healthyData);
                if app.CerebralPalsyCheckBox.Value == 1 %If other data needs to be plotted, hold on
                    hold(app.graph, 'on');
                end
            end
            
            if app.CerebralPalsyCheckBox.Value == 1
                plot(app.graph, cerebralPalsyData);
                if app.HealthyCheckBox.Value == 1 %Hold off if hold on was used before
                    hold(app.graph, 'off');
                end
            end
            
            if app.MaximaCheckBox.Value == 1
                calculateMaxima(app)
                plotcircles(app)
            end

            
        end
    
        
        function calculateMaxima(app)
            if app.smoothOn == true
                app.maxima = findpeaks(app.healthyDataSmoothed)
            else 
                app.maxima = findpeaks(app.healthyData)
            end
            app.maxima
            
        end
    
        
        function plotcircles(app)
            for i = 1:size(app.maxima)
                %hold(app.graph, 'on');
                if app.smoothOn == true
                    center=[find(app.healthyDataSmoothed==app.maxima(i)),app.maxima(i)]
                else
                    center=[find(app.healthyData==app.maxima(i)),app.maxima(i)]
                end
                viscircles(center,2)
            end
            
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           app.WindowSizeSlider.Enable = 'off';
        end

        % Button pushed function: OpenfileButton
        function OpenfileButtonPushed(app, event)
            warning('OFF', 'MATLAB:table:ModifiedAndSavedVarnames');
            [file, path] = uigetfile('*.xlsx');
            if isequal(file,0)          
                uialert(app.UIFigure, "Please input an Excel file.","Notification");
            else
                t = readtable(fullfile(path, file));
                app.UITable.Data = t;
            end
            fullpathname = strcat(path, file);
            app.CurrentfileEditField.Value = fullpathname;
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            selectedCells = event.Indices;
            [m,n] = size( selectedCells );
            firstRow    = selectedCells(1,1);   lastRow     = selectedCells(m,1);
            firstColumn = selectedCells(1,n);   lastColumn  = selectedCells(m,n);
            
            if firstColumn == lastColumn
                selectedData = app.UITable.Data(firstRow:lastRow,firstColumn);
                selectedData = table2array( selectedData );
                doubleData = str2double ( selectedData );

                if app.HealthyButton.Value == 1
                    app.healthyData(:,:) = [];
                    app.healthyData(1:m,1) = doubleData(1:m,1);
                    showData(app, app.healthyData, app.cerebralPalsyData);
                else 
                    app.cerebralPalsyData(:,:) = [];
                    app.cerebralPalsyData(1:m,1) = doubleData(1:m,1);
                    showData(app, app.healthyData, app.cerebralPalsyData);
                end
            else
                uialert(app.UIFigure, "Please only select one column! Changes have not been saved.","Notification");
            end
        end

        % Value changed function: HealthyCheckBox
        function HealthyCheckBoxValueChanged(app, event)
            showData(app, app.healthyData, app.cerebralPalsyData);
        end

        % Value changed function: CerebralPalsyCheckBox
        function CerebralPalsyCheckBoxValueChanged(app, event)
            showData(app, app.healthyData, app.cerebralPalsyData);
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            % Disable HELP button while dialog is open
            app.HelpButton.Enable = 'off';           
            % Open the HELP dialog and pass inputs
            app.HelpPopup = Help(app);
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            if app.smoothOn == false 
                 app.smoothOn = true;
                 app.WindowSizeSlider.Enable = 'on';
            else 
                 app.smoothOn = false;
                 app.WindowSizeSlider.Enable = 'off';
            end 
        end

        % Value changing function: WindowSizeSlider
        function WindowSizeSliderValueChanging(app, event)
            window = event.Value;
            if app.smoothOn == true
                app.healthyDataSmoothed = smoothdata( app.healthyData,'gaussian',window);
                app.cerebralPalsyDataSmoothed = smoothdata(app.cerebralPalsyData ,'gaussian',window);
                showData(app,app.healthyDataSmoothed, app.cerebralPalsyDataSmoothed);
            end
        end

        % Value changed function: MaximaCheckBox
        function MaximaCheckBoxValueChanged(app, event)
             value = app.MaximaCheckBox.Value;
               if app.smoothOn == true
                    showData(app,app.healthyDataSmoothed, app.cerebralPalsyDataSmoothed);
               else 
                    showData(app, app.healthyData, app.cerebralPalsyData);
               end         
        end

        % Value changed function: WindowSizeSlider
        function WindowSizeSliderValueChanged(app, event)
            value = app.WindowSizeSlider.Value;
            calculateMaxima(app);
        end

        % Button pushed function: SaveasButton
        function SaveasButtonPushed(app, event)
            [file path] = uiputfile('*.xlsx');
            if isequal(file,0)
                uialert(app.UIFigure, 'The data was not saved!','Notification');
            else
                comments = app.Comments.Value;
                window = app.WindowDropDown.Value;
                if app.smoothOn == true
                    smoothing = app.WindowSizeSlider.Value;
                    hData = app.healthyDataSmoothed;
                    cData = app.cerebralPalsyDataSmoothed;
                else
                    smoothing = -1;
                    hData = app.healthyData;
                    cData = app.cerebralPalsyData;
                end
                
                settings = [comments window smoothing];
                settings = array2table(settings, 'VariableNames', {'Comments', 'Window', 'Smoothing'});
                healthy = array2table(hData, 'VariableNames', {'HealthyData'});
                cerebralPalsy = array2table(cData, 'VariableNames', {'CerebralPalsyData'});
                
                fullPath = fullfile(path, file);
                writetable(settings, fullPath);
                writetable(healthy,fullPath,'Range','A3');
                writetable(cerebralPalsy,fullPath,'Range','B3');
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.MainPanel = uipanel(app.UIFigure);
            app.Tabs = uitabgroup(app.MainPanel);
            app.Data = uitab(app.Tabs);
            app.Data.Title = 'Data';
            app.UITable = uitable(app.Data);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.UITable.Tooltip = {' '};
            app.NextdataselectionButtonGroup = uibuttongroup(app.Data);
            app.NextdataselectionButtonGroup.Title = 'Next data selection:';
            app.HealthyButton = uiradiobutton(app.NextdataselectionButtonGroup);
            app.HealthyButton.Text = 'Healthy';
            app.HealthyButton.Value = true;
            app.CerebralPalsyButton = uiradiobutton(app.NextdataselectionButtonGroup);
            app.CerebralPalsyButton.Text = 'Cerebral Palsy';
            app.Graphs = uitab(app.Tabs);
            app.Graphs.Title = 'Graphs';
            app.GraphsPanel = uipanel(app.Graphs);
            app.graph = uiaxes(app.GraphsPanel);
            title(app.graph, 'Phase data')
            xlabel(app.graph, 'X')
            ylabel(app.graph, 'Y')
            app.TabGroup = uitabgroup(app.Graphs);
            app.PlotoptionsTab = uitab(app.TabGroup);
            app.PlotoptionsTab.Title = 'Plot options';
            app.SelectdataLabel = uilabel(app.PlotoptionsTab);
            app.SelectdataLabel.Text = 'Select data';
            app.HealthyCheckBox = uicheckbox(app.PlotoptionsTab);
            app.HealthyCheckBox.ValueChangedFcn = createCallbackFcn(app, @HealthyCheckBoxValueChanged, true);
            app.HealthyCheckBox.Text = 'Healthy';
            app.CerebralPalsyCheckBox = uicheckbox(app.PlotoptionsTab);
            app.CerebralPalsyCheckBox.ValueChangedFcn = createCallbackFcn(app, @CerebralPalsyCheckBoxValueChanged, true);
            app.CerebralPalsyCheckBox.Text = 'Cerebral Palsy';
            app.PeaksLabel = uilabel(app.PlotoptionsTab);
            app.PeaksLabel.Text = 'Peaks';
            app.MaximaCheckBox = uicheckbox(app.PlotoptionsTab);
            app.MaximaCheckBox.ValueChangedFcn = createCallbackFcn(app, @MaximaCheckBoxValueChanged, true);
            app.MaximaCheckBox.Text = 'Maxima';
            app.MinimaCheckBox_2 = uicheckbox(app.PlotoptionsTab);
            app.MinimaCheckBox_2.Text = 'Minima';
            app.FourieroptionsTab = uitab(app.TabGroup);
            app.FourieroptionsTab.Title = 'Fourier options';
            app.Label = uilabel(app.FourieroptionsTab);
            app.Label.Text = '';
            app.WindowDropDownLabel = uilabel(app.FourieroptionsTab);
            app.WindowDropDownLabel.Text = 'Window';
            app.WindowDropDown = uidropdown(app.FourieroptionsTab);
            app.DataoptionsTab = uitab(app.TabGroup);
            app.DataoptionsTab.Title = 'Data options';
            app.SmoothingLabel = uilabel(app.DataoptionsTab);
            app.SmoothingLabel.Text = 'Smoothing';
            app.Switch = uiswitch(app.DataoptionsTab, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.WindowSizeSliderLabel = uilabel(app.DataoptionsTab);
            app.WindowSizeSliderLabel.Text = 'Window Size';
            app.WindowSizeSlider = uislider(app.DataoptionsTab);
            app.WindowSizeSlider.Limits = [1 10];
            app.WindowSizeSlider.MajorTicks = [1 5 10];
            app.WindowSizeSlider.MajorTickLabels = {'1', '5', '10'};
            app.WindowSizeSlider.ValueChangedFcn = createCallbackFcn(app, @WindowSizeSliderValueChanged, true);
            app.WindowSizeSlider.ValueChangingFcn = createCallbackFcn(app, @WindowSizeSliderValueChanging, true);
            app.WindowSizeSlider.MinorTicks = [1 2 3 4 5 6 7 8 9];
            app.WindowSizeSlider.Value = 1;
            app.SaveTab = uitab(app.TabGroup);
            app.SaveTab.Title = 'Save data';
            app.CommentsTextAreaLabel = uilabel(app.SaveTab);
            app.CommentsTextAreaLabel.Text = 'Comments';
            app.Comments = uitextarea(app.SaveTab);
            app.Comments.Value = {'Here you can write comments that you want to save with the data.'};
            app.SaveasButton = uibutton(app.SaveTab, 'push');
            app.SaveasButton.ButtonPushedFcn = createCallbackFcn(app, @SaveasButtonPushed, true);
            app.SaveasButton.Text = 'Save as...';
            app.openFilePanel = uipanel(app.MainPanel);
            app.OpenfileButton = uibutton(app.openFilePanel, 'push');
            app.OpenfileButton.ButtonPushedFcn = createCallbackFcn(app, @OpenfileButtonPushed, true);
            app.OpenfileButton.Text = 'Open file';
            app.CurrentfileEditFieldLabel = uilabel(app.openFilePanel);
            app.CurrentfileEditFieldLabel.Text = 'Current file:';
            app.CurrentfileEditField = uieditfield(app.openFilePanel, 'text');
            app.CurrentfileEditField.Editable = 'off';
            app.HelpButton = uibutton(app.openFilePanel, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'Help';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end